classdef biLoc4ratingOptimal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        CenterPanel         matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        RightPanel          matlab.ui.container.Panel
        SaveButton          matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        SliderTab           matlab.ui.container.Tab
        c3Slider            matlab.ui.control.Slider
        c3SliderLabel       matlab.ui.control.Label
        c2Slider            matlab.ui.control.Slider
        c2SliderLabel       matlab.ui.control.Label
        c1Slider            matlab.ui.control.Slider
        c1SliderLabel       matlab.ui.control.Label
        sigCSlider          matlab.ui.control.Slider
        sigCSliderLabel     matlab.ui.control.Label
        pCommonSlider       matlab.ui.control.Slider
        pCommonSliderLabel  matlab.ui.control.Label
        sigPSlider          matlab.ui.control.Slider
        sigPSliderLabel     matlab.ui.control.Label
        sigV2Slider         matlab.ui.control.Slider
        sigV2Label          matlab.ui.control.Label
        sigASlider          matlab.ui.control.Slider
        sigASliderLabel     matlab.ui.control.Label
        sigV1Slider         matlab.ui.control.Slider
        sigV1SliderLabel    matlab.ui.control.Label
        bASlider            matlab.ui.control.Slider
        bASliderLabel       matlab.ui.control.Label
        aASlider            matlab.ui.control.Slider
        aASliderLabel       matlab.ui.control.Label
        TextTab             matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    methods (Access = private)

        function runSimulation(app)

            % free parameters
            if strcmp(app.SliderTab.Title, 'Slider')
                aA = app.aASlider.Value;
                bA = app.bASlider.Value;
                sigV1 = app.sigV1Slider.Value;
                sigA = app.sigASlider.Value;
                sigV2 = app.sigV2Slider.Value;
                sigP = app.sigPSlider.Value;
                pCommon = app.pCommonSlider.Value;
                sigC = app.sigCSlider.Value;
                c1 = app.c1Slider.Value;
                c2 = app.c2Slider.Value;
                c3 = app.c3Slider.Value;
            end

            % experimental info
            aud_level = [6 8 9 11];
            aud_VA = -30:4:30;
            sA = aud_VA(aud_level);
            sV = sA;

            % plot set up
            clt = [30, 120, 180; % blue
                227, 27, 27;  % dark red
                repmat(125, 1, 3)]./255;

            % model set up
            muP = 0;
            lapse = 0.2;
            sigVs = [sigV1, sigV2];
            num_rep = 1000;
            fixP.sigMotor = 1.36;
            fixP.model_ind = 3; % optimal model
            fixP.num_rep = num_rep;
            cue_label = {'Post-cue: A','Post-cue: V'};
            num_cue = numel(cue_label);
            rel_label = {'High reliability','Low reliability'};

            % simulate data
            [org_loc, org_conf] = deal(NaN(numel(sA), numel(sV), num_cue, numel(sigVs), num_rep));

            for a = 1:numel(sA)

                for v = 1:numel(sV)

                    for r = 1:numel(sigVs)

                        fixP.sA = sA(a);
                        fixP.sV = sV(v);

                        [org_loc(a,v,:,r,:), org_conf(a,v,:,r,:)] = simAllModels(...
                            aA, bA, sigA, sigVs(r), muP, sigP, pCommon, sigC, c1, c2, c3, lapse, fixP);

                    end

                end

            end

            %% check localization data

            uni_loc = zeros(size(org_loc));

            loc_a = repmat((sA*aA+bA)',[1,numel(sV)]);
            loc_v = repmat(sV,[numel(sA),1]);

            uni_loc(:,:,1,1:2,:) = repmat(loc_a, [1, 1, 1, 2, num_rep]);
            uni_loc(:,:,2,1:2,:) = repmat(loc_v, [1, 1, 1, 2, num_rep]);

            % loc at uni minus loc at bi
            ve =  mean(org_loc,5) - mean(uni_loc, 5);

            % diff x cue x reliability
            [ve_by_raw_diff, raw_diff] = org_by_raw_diffs_4D(ve, sA);

            % auditory localization
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4);
            cue = 1;
            title(app.UIAxes,cue_label{cue})
            hold(app.UIAxes, 'on');
            title(app.UIAxes,{sprintf('Optimal model, rep: %i', num_rep);...
                'Post-cue: A'})
            xlabel(app.UIAxes, 'Audiovisual discrepancy (V-A, deg)');
            ylabel(app.UIAxes, 'Shift of localization (deg)');

            for rel = 1: numel(sigVs)

                i_ve = squeeze(ve_by_raw_diff(:, cue, rel))';
                plot(app.UIAxes, raw_diff, i_ve, 'Color',clt(rel+1,:))

            end
            xlim(app.UIAxes, [min(raw_diff), max(raw_diff)])
            ylim(app.UIAxes, [-5, 5])
            xticks(app.UIAxes, raw_diff)
            yline(app.UIAxes, 0,'-')
            plot(app.UIAxes, raw_diff, raw_diff,'k--')

            % visual localization
            cue = 2;
            title(app.UIAxes2,cue_label{cue})
            hold(app.UIAxes2, 'on');
            title(app.UIAxes2,sprintf('Post-cue: V'))
            xlabel(app.UIAxes2, 'Audiovisual discrepancy (V-A, deg)');
            ylabel(app.UIAxes2, 'Shift of localization (deg)');

            for rel = 1: numel(sigVs)

                i_ve = squeeze(ve_by_raw_diff(:, cue, rel))';
                plot(app.UIAxes2, raw_diff, i_ve, 'Color',clt(rel+1,:))

            end
            xlim(app.UIAxes2, [min(raw_diff), max(raw_diff)])
            ylim(app.UIAxes2, [-5, 5])
            xticks(app.UIAxes2, raw_diff)
            yline(app.UIAxes2, 0,'-')
            plot(app.UIAxes2, raw_diff, -raw_diff,'k--')

            %% check confidence data

            % organize confidence data: {diff} cue x reliability x rep
            [conf_by_diff, all_diffs] = org_by_diffs(org_conf, sA);

            % auditory confidence
            cue = 1;
            title(app.UIAxes3,cue_label{cue})
            hold(app.UIAxes3, 'on');
            title(app.UIAxes3,{sprintf('Optimal model, rep: %i', num_rep);...
                'Post-cue: A'})
            xlabel(app.UIAxes3, 'Absolute audiovisual discrepancy (deg)');
            ylabel(app.UIAxes3, 'Confidence rating (1-4)');

            for rel = 1: numel(sigVs)
                [p_conf, se_conf] = deal(NaN(1, numel(all_diffs)));
                for diff = 1:numel(all_diffs)
                    i_conf = squeeze(conf_by_diff{diff}(cue, rel, :))';
                    p = sum(i_conf)/(numel(i_conf));
                    p_conf(diff) = p;
                    se_conf(diff) = sqrt((p*(1-p))/numel(i_conf));
                end
                plot(app.UIAxes3, all_diffs, p_conf, 'Color',clt(rel+1,:));
                ylim(app.UIAxes3, [1, 4]) % rating range
            end
            xticks(app.UIAxes3, all_diffs)
            yticks(app.UIAxes3, 1:4)

            % visual confidence
            cue = 2;
            title(app.UIAxes4,cue_label{cue})
            hold(app.UIAxes4, 'on');
            title(app.UIAxes4,'Post-cue: V')
            xlabel(app.UIAxes4, 'Absolute audiovisual discrepancy (deg)');
            ylabel(app.UIAxes4, 'Confidence rating (1-4)');

            for rel = 1: numel(sigVs)
                [p_conf, se_conf] = deal(NaN(1, numel(all_diffs)));
                for diff = 1:numel(all_diffs)
                    i_conf = squeeze(conf_by_diff{diff}(cue, rel, :))';
                    p = sum(i_conf)/(numel(i_conf));
                    p_conf(diff) = p;
                    se_conf(diff) = sqrt((p*(1-p))/numel(i_conf));
                end
                plot(app.UIAxes4, all_diffs, p_conf, 'Color',clt(rel+1,:));
                ylim(app.UIAxes4, [1, 4]) % rating range
            end
            xticks(app.UIAxes4, all_diffs)
            yticks(app.UIAxes4, 1:4)
        end
        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            runSimulation(app)

        end

        % Value changing function: aASlider
        function aASliderValueChanging(app, event)

            app.aASlider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: bASlider
        function bASliderValueChanging(app, event)
            
            app.bASlider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: sigV1Slider
        function sigV1SliderValueChanging(app, event)
            
            app.sigV1Slider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: sigASlider
        function sigASliderValueChanging(app, event)
            app.sigASlider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: sigV2Slider
        function sigV2SliderValueChanging(app, event)
            app.sigV2Slider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: sigPSlider
        function sigPSliderValueChanging(app, event)
            app.sigPSlider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: pCommonSlider
        function pCommonSliderValueChanging(app, event)
            app.pCommonSlider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: sigCSlider
        function sigCSliderValueChanging(app, event)
            app.sigCSlider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: c1Slider
        function c1SliderValueChanging(app, event)
            app.c1Slider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: c2Slider
        function c2SliderValueChanging(app, event)
            app.c2Slider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changing function: c3Slider
        function c3SliderValueChanging(app, event)
            app.c3Slider.Value = event.Value;
            runSimulation(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {633, 633, 633};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {633, 633};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {391, '1x', 378};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SliderTab = uitab(app.TabGroup);
            app.SliderTab.Title = 'Slider';
            app.aASliderLabel = uilabel(app.SliderTab);
            app.aASliderLabel.Text = 'aA';
            app.aASlider = uislider(app.SliderTab);
            app.aASlider.Limits = [0.5 2];
            app.aASlider.ValueChangingFcn = createCallbackFcn(app, @aASliderValueChanging, true);
            app.aASlider.Value = 1;
            app.bASliderLabel = uilabel(app.SliderTab);
            app.bASliderLabel.Text = 'bA';
            app.bASlider = uislider(app.SliderTab);
            app.bASlider.Limits = [-10 10];
            app.bASlider.ValueChangingFcn = createCallbackFcn(app, @bASliderValueChanging, true);
            app.bASlider.Value = -0.5;
            app.sigV1SliderLabel = uilabel(app.SliderTab);
            app.sigV1SliderLabel.Text = 'sigV1';
            app.sigV1Slider = uislider(app.SliderTab);
            app.sigV1Slider.Limits = [0.01 3];
            app.sigV1Slider.ValueChangingFcn = createCallbackFcn(app, @sigV1SliderValueChanging, true);
            app.sigV1Slider.Value = 0.5;
            app.sigASliderLabel = uilabel(app.SliderTab);
            app.sigASliderLabel.Text = 'sigA';
            app.sigASlider = uislider(app.SliderTab);
            app.sigASlider.Limits = [1 10];
            app.sigASlider.ValueChangingFcn = createCallbackFcn(app, @sigASliderValueChanging, true);
            app.sigASlider.Value = 1.5;
            app.sigV2Label = uilabel(app.SliderTab);
            app.sigV2Label.Text = 'sigV2';
            app.sigV2Slider = uislider(app.SliderTab);
            app.sigV2Slider.Limits = [1 10];
            app.sigV2Slider.ValueChangingFcn = createCallbackFcn(app, @sigV2SliderValueChanging, true);
            app.sigV2Slider.Value = 1.8;
            app.sigPSliderLabel = uilabel(app.SliderTab);
            app.sigPSliderLabel.Text = 'sigP';
            app.sigPSlider = uislider(app.SliderTab);
            app.sigPSlider.Limits = [1 10];
            app.sigPSlider.ValueChangingFcn = createCallbackFcn(app, @sigPSliderValueChanging, true);
            app.sigPSlider.Value = 8;
            app.pCommonSliderLabel = uilabel(app.SliderTab);
            app.pCommonSliderLabel.Text = 'pCommon';
            app.pCommonSlider = uislider(app.SliderTab);
            app.pCommonSlider.Limits = [0 1];
            app.pCommonSlider.ValueChangingFcn = createCallbackFcn(app, @pCommonSliderValueChanging, true);
            app.pCommonSlider.Value = 0.5;
            app.sigCSliderLabel = uilabel(app.SliderTab);
            app.sigCSliderLabel.Text = 'sigC';
            app.sigCSlider = uislider(app.SliderTab);
            app.sigCSlider.Limits = [0.1 5];
            app.sigCSlider.ValueChangingFcn = createCallbackFcn(app, @sigCSliderValueChanging, true);
            app.sigCSlider.Value = 1;
            app.c1SliderLabel = uilabel(app.SliderTab);
            app.c1SliderLabel.Text = 'c1';
            app.c1Slider = uislider(app.SliderTab);
            app.c1Slider.Limits = [0.5 5];
            app.c1Slider.ValueChangingFcn = createCallbackFcn(app, @c1SliderValueChanging, true);
            app.c1Slider.Value = 0.5;
            app.c2SliderLabel = uilabel(app.SliderTab);
            app.c2SliderLabel.Text = 'c2';
            app.c2Slider = uislider(app.SliderTab);
            app.c2Slider.Limits = [0.5 5];
            app.c2Slider.ValueChangingFcn = createCallbackFcn(app, @c2SliderValueChanging, true);
            app.c2Slider.Value = 1.5;
            app.c3SliderLabel = uilabel(app.SliderTab);
            app.c3SliderLabel.Text = 'c3';
            app.c3Slider = uislider(app.SliderTab);
            app.c3Slider.Limits = [0.5 5];
            app.c3Slider.ValueChangingFcn = createCallbackFcn(app, @c3SliderValueChanging, true);
            app.c3Slider.Value = 2.5;
            app.TextTab = uitab(app.TabGroup);
            app.TextTab.Title = 'Text';
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biLoc4ratingOptimal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end