classdef biLoc4ratingAllModels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
        CenterPanel         matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        UIAxes8             matlab.ui.control.UIAxes
        UIAxes7             matlab.ui.control.UIAxes
        UIAxes6             matlab.ui.control.UIAxes
        UIAxes5             matlab.ui.control.UIAxes
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        RightPanel          matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        SliderTab           matlab.ui.container.Tab
        ka2Slider           matlab.ui.control.Slider
        ka2SliderLabel      matlab.ui.control.Label
        kv2Slider           matlab.ui.control.Slider
        kv2SliderLabel      matlab.ui.control.Label
        kv1Slider           matlab.ui.control.Slider
        kv1SliderLabel      matlab.ui.control.Label
        SaveButton          matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
        c3Slider            matlab.ui.control.Slider
        c3SliderLabel       matlab.ui.control.Label
        c2Slider            matlab.ui.control.Slider
        c2SliderLabel       matlab.ui.control.Label
        c1Slider            matlab.ui.control.Slider
        c1SliderLabel       matlab.ui.control.Label
        sigCSlider          matlab.ui.control.Slider
        sigCSliderLabel     matlab.ui.control.Label
        pCommonSlider       matlab.ui.control.Slider
        pCommonSliderLabel  matlab.ui.control.Label
        sigPSlider          matlab.ui.control.Slider
        sigPSliderLabel     matlab.ui.control.Label
        sigV2Slider         matlab.ui.control.Slider
        sigV2Label          matlab.ui.control.Label
        sigASlider          matlab.ui.control.Slider
        sigASliderLabel     matlab.ui.control.Label
        sigV1Slider         matlab.ui.control.Slider
        sigV1SliderLabel    matlab.ui.control.Label
        bASlider            matlab.ui.control.Slider
        bASliderLabel       matlab.ui.control.Label
        aASlider            matlab.ui.control.Slider
        aASliderLabel       matlab.ui.control.Label
        TextTab             matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    methods (Access = private)

        function runSimulation(app)

            % clear figures
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxes6);
            cla(app.UIAxes7);
            cla(app.UIAxes8);

            % free parameters
            if strcmp(app.SliderTab.Title, 'Slider')
                aA = app.aASlider.Value;
                bA = app.bASlider.Value;
                sigV1 = app.sigV1Slider.Value;
                sigA = app.sigASlider.Value;
                sigV2 = app.sigV2Slider.Value;
                sigP = app.sigPSlider.Value;
                pCommon = app.pCommonSlider.Value;
                sigC = app.sigCSlider.Value;
                c1 = app.c1Slider.Value;
                dc2 = app.c2Slider.Value;
                dc3 = app.c3Slider.Value;
                kv1 = app.kv1Slider.Value;
                kv2 = app.kv2Slider.Value;
                ka2 = app.ka2Slider.Value;
            end

            c2 = c1+dc2;
            c3 = c1+dc2+dc3;
            cs = [c1, c2, c3; kv1*[c1, c2, c3]; ka2 * [c1, c2, c3]; kv2 * [c1, c2, c3]]';

            % experimental info
            sA = -10:2:10;%aud_VA(aud_level);
            sV = sA;

            % plot set up
            clt = [30, 120, 180; % blue
                227, 27, 27;  % dark red
                repmat(125, 1, 3)]./255;

            % model set up
            muP = 0;
            lapse = 0.02;
            sigVs = [sigV1, sigV2];
            num_rep = 1000;
            fixP.sigMotor = 1.36;
            fixP.num_rep = num_rep;
            ds_conf = {'Heuristic','Suboptimal','Optimal'};
            cue_label = {'Post-cue: A','Post-cue: V'};
            num_cue = numel(cue_label);
            rel_label = {'High visual reliability','Low visual reliability'};

            % simulate data
            for d = 1:3

                fixP.model_ind = d;
                fixP.bi_nrep = num_rep;

%                 % set criterion based on other free parameters
%                 M_fixP.sA = sA;
%                 M_fixP.sV = sV;
%                 M_fixP.model_ind = d;
%                 M_fixP.num_rep = num_rep;
% 
%                 [lb, ub] = findConfRange(aA, bA, sigA, sigV1, sigV2, sigP, pCommon, M_fixP);
%                 intervals = linspace(lb, ub, 5);
%                 c1 = intervals(2);
%                 c2 = intervals(3);
%                 c3 = intervals(4);

                for a = 1:numel(sA)

                    for v = 1:numel(sV)

                        for rr = 1:numel(sigVs)

                            fixP.bi_sA = sA(a);
                            fixP.bi_sV = sV(v);

                            [org_loc{d}(a,v,:,rr,:), org_conf{d}(a,v,:,rr,:)] = simAllModels(...
                                aA, bA, sigA, sigVs(rr), muP, sigP, pCommon, sigC, cs(1,rr*2-1:rr*2), cs(2,rr*2-1:rr*2), cs(3,rr*2-1:rr*2), lapse, fixP);

                        end

                    end

                end

            end

            %% check localization data

            uni_loc = zeros(size(org_loc{3}));

            loc_a = repmat((sA*aA+bA)',[1,numel(sV)]);
            loc_v = repmat(sV,[numel(sA),1]);

            uni_loc(:,:,1,1:2,:) = repmat(loc_a, [1, 1, 1, 2, num_rep]);
            uni_loc(:,:,2,1:2,:) = repmat(loc_v, [1, 1, 1, 2, num_rep]);

            % loc at uni minus loc at bi
            ve =  mean(org_loc{3},5) - mean(uni_loc, 5);

            % diff x cue x reliability
            [ve_by_raw_diff, raw_diff] = org_by_raw_diffs_4D(ve, sA);

            % auditory localization
            cue = 1;
            title(app.UIAxes,cue_label{cue})
            hold(app.UIAxes, 'on');
            title(app.UIAxes,{sprintf('Optimal model, rep: %i', num_rep);...
                'Post-cue: A'})
            xlabel(app.UIAxes, 'Audiovisual discrepancy (V-A, deg)');
            ylabel(app.UIAxes, 'Shift of localization (deg)');

            for rel = 1: numel(sigVs)

                i_ve = squeeze(ve_by_raw_diff(:, cue, rel))';
                plot(app.UIAxes, raw_diff, i_ve, 'Color', clt(rel+1,:))

            end
            xlim(app.UIAxes, [min(raw_diff), max(raw_diff)])
            ylim(app.UIAxes, [-5, 5])
            xticks(app.UIAxes, raw_diff)
            yline(app.UIAxes, 0,'-')
            plot(app.UIAxes, raw_diff, raw_diff,'k--')
            legend(app.UIAxes, rel_label,'Location','southeast')

            % visual localization
            cue = 2;
            title(app.UIAxes2,cue_label{cue})
            hold(app.UIAxes2, 'on');
            title(app.UIAxes2,sprintf('Post-cue: V'))
            xlabel(app.UIAxes2, 'Audiovisual discrepancy (V-A, deg)');
            ylabel(app.UIAxes2, 'Shift of localization (deg)');

            for rel = 1: numel(sigVs)

                i_ve = squeeze(ve_by_raw_diff(:, cue, rel))';
                plot(app.UIAxes2, raw_diff, i_ve, 'Color',clt(rel+1,:))

            end
            xlim(app.UIAxes2, [min(raw_diff), max(raw_diff)])
            ylim(app.UIAxes2, [-5, 5])
            xticks(app.UIAxes2, raw_diff)
            yline(app.UIAxes2, 0,'-')
            plot(app.UIAxes2, raw_diff, -raw_diff,'k--')

            %% confidence by M1

            % organize confidence data: {diff} cue x reliability x rep
            d = 1;
            [conf_by_diff, all_diffs] = org_by_diffs(org_conf{d}, sA);

            % auditory confidence
            cue = 1;
            title(app.UIAxes3,cue_label{cue})
            hold(app.UIAxes3, 'on');
            title(app.UIAxes3,{sprintf('%s model, rep: %i', ds_conf{d}, num_rep);...
                'Post-cue: A'})
            ylabel(app.UIAxes3, 'Confidence rating (1-4)');

            for rel = 1: numel(sigVs)
                [p_conf, se_conf] = deal(NaN(1, numel(all_diffs)));
                for diff = 1:numel(all_diffs)
                    i_conf = squeeze(conf_by_diff{diff}(cue, rel, :))';
                    p = sum(i_conf)/(numel(i_conf));
                    p_conf(diff) = p;
                    se_conf(diff) = sqrt((p*(1-p))/numel(i_conf));
                end
                plot(app.UIAxes3, all_diffs, p_conf, 'Color',clt(rel+1,:));
                ylim(app.UIAxes3, [1, 4]) % rating range
            end
            xticks(app.UIAxes3, all_diffs)
            yticks(app.UIAxes3, 1:4)

            % visual confidence
            cue = 2;
            title(app.UIAxes4,cue_label{cue})
            hold(app.UIAxes4, 'on');
            title(app.UIAxes4,'Post-cue: V')
            ylabel(app.UIAxes4, 'Confidence rating (1-4)');

            for rel = 1: numel(sigVs)
                [p_conf, se_conf] = deal(NaN(1, numel(all_diffs)));
                for diff = 1:numel(all_diffs)
                    i_conf = squeeze(conf_by_diff{diff}(cue, rel, :))';
                    p = sum(i_conf)/(numel(i_conf));
                    p_conf(diff) = p;
                    se_conf(diff) = sqrt((p*(1-p))/numel(i_conf));
                end
                plot(app.UIAxes4, all_diffs, p_conf, 'Color',clt(rel+1,:));
                ylim(app.UIAxes4, [1, 4]) % rating range
            end
            xticks(app.UIAxes4, all_diffs)
            yticks(app.UIAxes4, 1:4)

            %% confidence by M2

            % organize confidence data: {diff} cue x reliability x rep
            d = 2;
            [conf_by_diff, all_diffs] = org_by_diffs(org_conf{d}, sA);

            % auditory confidence
            cue = 1;
            title(app.UIAxes5,cue_label{cue})
            hold(app.UIAxes5, 'on');
            title(app.UIAxes5,{sprintf('%s model, rep: %i', ds_conf{d}, num_rep);...
                'Post-cue: A'})
            xlabel(app.UIAxes5, 'Absolute audiovisual discrepancy (deg)');
            ylabel(app.UIAxes5, 'Confidence rating (1-4)');

            for rel = 1: numel(sigVs)
                [p_conf, se_conf] = deal(NaN(1, numel(all_diffs)));
                for diff = 1:numel(all_diffs)
                    i_conf = squeeze(conf_by_diff{diff}(cue, rel, :))';
                    p = sum(i_conf)/(numel(i_conf));
                    p_conf(diff) = p;
                    se_conf(diff) = sqrt((p*(1-p))/numel(i_conf));
                end
                plot(app.UIAxes5, all_diffs, p_conf, 'Color',clt(rel+1,:));
                ylim(app.UIAxes5, [1, 4]) % rating range
            end
            xticks(app.UIAxes5, all_diffs)
            yticks(app.UIAxes5, 1:4)

            % visual confidence
            cue = 2;
            title(app.UIAxes6,cue_label{cue})
            hold(app.UIAxes6, 'on');
            title(app.UIAxes6,'Post-cue: V')
            xlabel(app.UIAxes6, 'Absolute audiovisual discrepancy (deg)');
            ylabel(app.UIAxes6, 'Confidence rating (1-4)');

            for rel = 1: numel(sigVs)
                [p_conf, se_conf] = deal(NaN(1, numel(all_diffs)));
                for diff = 1:numel(all_diffs)
                    i_conf = squeeze(conf_by_diff{diff}(cue, rel, :))';
                    p = sum(i_conf)/(numel(i_conf));
                    p_conf(diff) = p;
                    se_conf(diff) = sqrt((p*(1-p))/numel(i_conf));
                end
                plot(app.UIAxes6, all_diffs, p_conf, 'Color',clt(rel+1,:));
                ylim(app.UIAxes6, [1, 4]) % rating range
            end
            xticks(app.UIAxes6, all_diffs)
            yticks(app.UIAxes6, 1:4)

            %% confidence by M3

            % organize confidence data: {diff} cue x reliability x rep
            d = 3;
            [conf_by_diff, all_diffs] = org_by_diffs(org_conf{d}, sA);

            % auditory confidence
            cue = 1;
            title(app.UIAxes7,cue_label{cue})
            hold(app.UIAxes7, 'on');
            title(app.UIAxes7,{sprintf('%s model, rep: %i', ds_conf{d}, num_rep);...
                'Post-cue: A'})
            ylabel(app.UIAxes7, 'Confidence rating (1-4)');

            for rel = 1: numel(sigVs)
                [p_conf, se_conf] = deal(NaN(1, numel(all_diffs)));
                for diff = 1:numel(all_diffs)
                    i_conf = squeeze(conf_by_diff{diff}(cue, rel, :))';
                    p = sum(i_conf)/(numel(i_conf));
                    p_conf(diff) = p;
                    se_conf(diff) = sqrt((p*(1-p))/numel(i_conf));
                end
                plot(app.UIAxes7, all_diffs, p_conf, 'Color',clt(rel+1,:));
                ylim(app.UIAxes7, [1, 4]) % rating range
            end
            xticks(app.UIAxes7, all_diffs)
            yticks(app.UIAxes7, 1:4)

            % visual confidence
            cue = 2;
            title(app.UIAxes8,cue_label{cue})
            hold(app.UIAxes8, 'on');
            title(app.UIAxes8,'Post-cue: V')
            ylabel(app.UIAxes8, 'Confidence rating (1-4)');

            for rel = 1: numel(sigVs)
                [p_conf, se_conf] = deal(NaN(1, numel(all_diffs)));
                for diff = 1:numel(all_diffs)
                    i_conf = squeeze(conf_by_diff{diff}(cue, rel, :))';
                    p = sum(i_conf)/(numel(i_conf));
                    p_conf(diff) = p;
                    se_conf(diff) = sqrt((p*(1-p))/numel(i_conf));
                end
                plot(app.UIAxes8, all_diffs, p_conf, 'Color',clt(rel+1,:));
                ylim(app.UIAxes8, [1, 4]) % rating range
            end
            xticks(app.UIAxes8, all_diffs)
            yticks(app.UIAxes8, 1:4)

        end
        
        function saveParam(app)

            out_dir = fullfile(pwd, 'GUIAllModels');
            if ~exist(out_dir, "dir"); mkdir(out_dir); end

            param.aA = app.aASlider.Value;
            param.bA = app.bASlider.Value;
            param.sigV1 = app.sigV1Slider.Value;
            param.sigA = app.sigASlider.Value;
            param.sigV2 = app.sigV2Slider.Value;
            param.sigP = app.sigPSlider.Value;
            param.pCommon = app.pCommonSlider.Value;
            param.sigC = app.sigCSlider.Value;
            param.c1 = app.c1Slider.Value;
            param.c2 = app.c2Slider.Value;
            param.c3 = app.c3Slider.Value;

            save(fullfile(out_dir, sprintf('param_%s', datestr(now, 'yy-mm-dd_HH-MM'))), "param");

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            runSimulation(app)

        end

        % Value changing function: aASlider
        function aASliderValueChanging(app, event)

            app.aASlider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: bASlider
        function bASliderValueChanging(app, event)

            app.bASlider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: sigV1Slider
        function sigV1SliderValueChanging(app, event)

            app.sigV1Slider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: sigASlider
        function sigASliderValueChanging(app, event)
            app.sigASlider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: sigV2Slider
        function sigV2SliderValueChanging(app, event)
            app.sigV2Slider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: sigPSlider
        function sigPSliderValueChanging(app, event)
            app.sigPSlider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: pCommonSlider
        function pCommonSliderValueChanging(app, event)
            app.pCommonSlider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: sigCSlider
        function sigCSliderValueChanging(app, event)
            app.sigCSlider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: c1Slider
        function c1SliderValueChanging(app, event)
            app.c1Slider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: c2Slider
        function c2SliderValueChanging(app, event)
            app.c2Slider.Value = event.Value;
            runSimulation(app)

        end

        % Value changing function: c3Slider
        function c3SliderValueChanging(app, event)
            app.c3Slider.Value = event.Value;
            runSimulation(app)

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            saveParam(app)

        end

        % Value changing function: kv1Slider
        function kv1SliderValueChanging(app, event)
            app.kv1Slider.Value = event.Value;
            runSimulation(app)
            
        end

        % Value changed function: kv2Slider
        function kv2SliderValueChanged(app, event)
            app.kv2Slider.Value = event.Value;
            runSimulation(app)
        end

        % Value changed function: ka2Slider
        function ka2SliderValueChanged(app, event)
           app.ka2Slider.Value = event.Value;
            runSimulation(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {628, 628, 628};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {628, 628};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {391, '1x', 579};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout3);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.GridLayout3);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout3);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.GridLayout3);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SliderTab = uitab(app.TabGroup);
            app.SliderTab.Title = 'Slider';
            app.aASliderLabel = uilabel(app.SliderTab);
            app.aASliderLabel.Text = 'aA';
            app.aASlider = uislider(app.SliderTab);
            app.aASlider.Limits = [0.5 3];
            app.aASlider.ValueChangingFcn = createCallbackFcn(app, @aASliderValueChanging, true);
            app.aASlider.Value = 0.53;
            app.bASliderLabel = uilabel(app.SliderTab);
            app.bASliderLabel.Text = 'bA';
            app.bASlider = uislider(app.SliderTab);
            app.bASlider.Limits = [-15 15];
            app.bASlider.ValueChangingFcn = createCallbackFcn(app, @bASliderValueChanging, true);
            app.bASlider.Value = 7.28;
            app.sigV1SliderLabel = uilabel(app.SliderTab);
            app.sigV1SliderLabel.Text = 'sigV1';
            app.sigV1Slider = uislider(app.SliderTab);
            app.sigV1Slider.Limits = [0.1 10];
            app.sigV1Slider.ValueChangingFcn = createCallbackFcn(app, @sigV1SliderValueChanging, true);
            app.sigV1Slider.Value = 2.35;
            app.sigASliderLabel = uilabel(app.SliderTab);
            app.sigASliderLabel.Text = 'sigA';
            app.sigASlider = uislider(app.SliderTab);
            app.sigASlider.Limits = [1 15];
            app.sigASlider.ValueChangingFcn = createCallbackFcn(app, @sigASliderValueChanging, true);
            app.sigASlider.Value = 5.17;
            app.sigV2Label = uilabel(app.SliderTab);
            app.sigV2Label.Text = 'sigV2';
            app.sigV2Slider = uislider(app.SliderTab);
            app.sigV2Slider.Limits = [1 10];
            app.sigV2Slider.ValueChangingFcn = createCallbackFcn(app, @sigV2SliderValueChanging, true);
            app.sigV2Slider.Value = 3.14;
            app.sigPSliderLabel = uilabel(app.SliderTab);
            app.sigPSliderLabel.Text = 'sigP';
            app.sigPSlider = uislider(app.SliderTab);
            app.sigPSlider.Limits = [1 20];
            app.sigPSlider.ValueChangingFcn = createCallbackFcn(app, @sigPSliderValueChanging, true);
            app.sigPSlider.Value = 11.82;
            app.pCommonSliderLabel = uilabel(app.SliderTab);
            app.pCommonSliderLabel.Text = 'pCommon';
            app.pCommonSlider = uislider(app.SliderTab);
            app.pCommonSlider.Limits = [0 1];
            app.pCommonSlider.ValueChangingFcn = createCallbackFcn(app, @pCommonSliderValueChanging, true);
            app.pCommonSlider.Value = 0.93;
            app.sigCSliderLabel = uilabel(app.SliderTab);
            app.sigCSliderLabel.Text = 'sigC';
            app.sigCSlider = uislider(app.SliderTab);
            app.sigCSlider.Limits = [0.1 10];
            app.sigCSlider.ValueChangingFcn = createCallbackFcn(app, @sigCSliderValueChanging, true);
            app.sigCSlider.Value = 1;
            app.c1SliderLabel = uilabel(app.SliderTab);
            app.c1SliderLabel.Text = 'c1';
            app.c1Slider = uislider(app.SliderTab);
            app.c1Slider.Limits = [0.5 5];
            app.c1Slider.ValueChangingFcn = createCallbackFcn(app, @c1SliderValueChanging, true);
            app.c1Slider.Value = 0.5;
            app.c2SliderLabel = uilabel(app.SliderTab);
            app.c2SliderLabel.Text = 'c2';
            app.c2Slider = uislider(app.SliderTab);
            app.c2Slider.Limits = [0.1 10];
            app.c2Slider.ValueChangingFcn = createCallbackFcn(app, @c2SliderValueChanging, true);
            app.c2Slider.Value = 4;
            app.c3SliderLabel = uilabel(app.SliderTab);
            app.c3SliderLabel.Text = 'c3';
            app.c3Slider = uislider(app.SliderTab);
            app.c3Slider.Limits = [0.1 10];
            app.c3Slider.ValueChangingFcn = createCallbackFcn(app, @c3SliderValueChanging, true);
            app.c3Slider.Value = 7;
            app.StartButton = uibutton(app.SliderTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.SliderTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.kv1SliderLabel = uilabel(app.SliderTab);
            app.kv1SliderLabel.Text = 'kv1';
            app.kv1Slider = uislider(app.SliderTab);
            app.kv1Slider.Limits = [0.1 10];
            app.kv1Slider.ValueChangingFcn = createCallbackFcn(app, @kv1SliderValueChanging, true);
            app.kv1Slider.Value = 0.5;
            app.kv2SliderLabel = uilabel(app.SliderTab);
            app.kv2SliderLabel.Text = 'kv2';
            app.kv2Slider = uislider(app.SliderTab);
            app.kv2Slider.Limits = [0.1 10];
            app.kv2Slider.ValueChangedFcn = createCallbackFcn(app, @kv2SliderValueChanged, true);
            app.kv2Slider.Value = 0.8;
            app.ka2SliderLabel = uilabel(app.SliderTab);
            app.ka2SliderLabel.Text = 'ka2';
            app.ka2Slider = uislider(app.SliderTab);
            app.ka2Slider.Limits = [0.1 10];
            app.ka2Slider.ValueChangedFcn = createCallbackFcn(app, @ka2SliderValueChanged, true);
            app.ka2Slider.Value = 1.2;
            app.TextTab = uitab(app.TabGroup);
            app.TextTab.Title = 'Text';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = biLoc4ratingAllModels

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end